classdef Boundary < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Menu                    matlab.ui.container.Menu
        LoadData                matlab.ui.container.Menu
        SaveData                matlab.ui.container.Menu
        FeatureExtractButton    matlab.ui.container.Menu
        Menu_7                  matlab.ui.container.Menu
        registrationMenuButton  matlab.ui.container.Menu
        Menu_8                  matlab.ui.container.Menu
        UIAxes                  matlab.ui.control.UIAxes
        Label                   matlab.ui.control.Label
        EditField               matlab.ui.control.NumericEditField
        Label_2                 matlab.ui.control.Label
        ListBox                 matlab.ui.control.ListBox
        ClearDataButton         matlab.ui.control.Button
        seceltpc                matlab.ui.control.Button
        Label_3                 matlab.ui.control.Label
        FeatureNameListBox      matlab.ui.control.ListBox
        ResulttextArea          matlab.ui.control.TextArea
        Label_4                 matlab.ui.control.Label
    end

    
    properties (Access = public )
        
        pc_number; % 点云的数量
        pc_name;   % 点云s的名称 数组
        pcs;       % 点云数据
        seceltpcButton_idx;
        cloudpont_seceltpcButtonPushed;
        FeatureExtract_cloudpoint_ ;
        Featurepcs_Names;
        Boundary_Colormatrix;
        
        FeatureAPP;
        PcloudRegisterAPP;
        %         ClockInformation;
        %         ResulttextAreaMessages;
        
    end
    
    methods (Access = private)
        
        function updata(app)
            
            app.EditField.Value = app.pc_number;  % 更新点云数据再editfield中的显示
            app.ListBox.Items = app.pc_name;
            app.FeatureNameListBox.Items  = app.Featurepcs_Names;            
            
        end
        
        function pcshow_self(app,pointcloud_A)
            
            %                 scatter3(app.UIAxes,pointcloud_A.Location(:,1),pointcloud_A.Location(:,2),pointcloud_A.Location(:,3));
            plot3(app.UIAxes,pointcloud_A.Location(:,1),pointcloud_A.Location(:,2),pointcloud_A.Location(:,3),'.');
            hold(app.UIAxes,'on');
            
        end
        
    end
    
    methods (Access = public)
        
        function Boundary_updata_Feature(app , feature)  %% 在当前文件添加特征信息
            
            cloudpoint_ = app.pcs(app.seceltpcButton_idx);
            
            cloudpoint_ = cloudpoint_{1};
            
            cloudpoint_.Feature = feature;
            
            app.pcs(app.seceltpcButton_idx) = {cloudpoint_};
            
%             if( length( app.Featurepcs_Names ) > 1)
%                 
%                app.registrationMenuButton.Enable = 'on';
%                 
%             end
            
        end
        
        function Boundary_delete_Feature(app)  %% 删除当前文件的特征信息
            
            cloudpoint_ = app.pcs(app.seceltpcButton_idx);
            
            cloudpoint_ = cloudpoint_{1};
            
            cloudpoint_.Feature = [];
            
            app.pcs(app.seceltpcButton_idx) = {cloudpoint_};
            
            indx = strcmp(app.Featurepcs_Names,app.FeatureExtract_cloudpoint_{1}.Name);
            app.Featurepcs_Names = app.Featurepcs_Names(not(indx));
            
            app.updata;
            
        end
        
        
        function ClockInformation = readClock(app)  %% 读取时间
            
            A = clock;
            A = A(4:end);
            ClockInformation = strcat( num2str(A(1)),'-');
            ClockInformation = strcat( ClockInformation,num2str(A(2)));
            ClockInformation = strcat( [ClockInformation '-'],num2str(floor(A(3))));
            
        end
        
        
        function UpLoadResultTextArea(app,Message_)
            
            app.ResulttextArea.Value{length(app.ResulttextArea.Value) + 1}= [app.readClock ': ' Message_];
%             scroll(app.ResulttextArea,'bottom')  % version 2020
            
        end
        
    
        function Notionmess(app,message)  % 显示 message 信息，无响应
            
            selection = uiconfirm(app.UIFigure,message,...
                'Confirmation');
            switch selection
                
                case '确定'
                    return;
                    
                case '取消'
                    return
                    
            end
            
        end


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            app.pc_number = 0; %  点云数量 初值为 0
            app.pcs = {};
            app.pc_name = {};
            app.FeatureExtractButton.Enable = 'off';
            app.registrationMenuButton.Enable= 'off';
            app.seceltpcButton_idx=[];
            app.cloudpont_seceltpcButtonPushed=[];
            app.FeatureAPP; % 特征提取APP
            app.PcloudRegisterAPP; %配准APP
            app.Featurepcs_Names = {};
            app.UpLoadResultTextArea('Application start !');
                        
            app.Boundary_Colormatrix = [1,0,0;
                0,1,0;
                0,0,1;
                1,0,1;
                1,0,1;
                0,1,1];                        
            
            %% 设置 当前文件夹路径
            
            
            cur_p1 = mfilename('fullpath');  % 运行的文件所在的路径为：cur_p1            
            cur_p2 = strfind(cur_p1,'\'); % 最后一根\符号的位置
            cur_p1 = cur_p1(1:cur_p2(end)-1);
            
            addpath(genpath([cur_p1,'\function']));
            

        end

        % Menu selected function: LoadData
        function LoadDataMenuSelected(app, event)
            %% 加载数据 响应
            
            
            %% 加载第一组数据
            [fileName,pathName] = uigetfile('*.pcd*','Please select an .pcd file');%文件筐，选择文件
            
            fileName_ = fileName;
            
            if(strcmp(fileName(1,end-3:end),'.pcd'))
                
                fileName = strcat(pathName,fileName);
                fileName = lower(fileName);%一致的小写字母形式
                
                app.pc_number = app.pc_number + 1;
                
                pointcloudA = pcread(fileName);
                
                app.pcshow_self(pointcloudA)
                
                pcloud = PointCloud_(fileName_,pointcloudA);
                
                app.pcs{app.pc_number} = pcloud;
                
                app.pc_name{app.pc_number} = pcloud.Name;
                
                updata(app);
                
                app.UpLoadResultTextArea(['loaded ',fileName_]);
                
                
            else
                
                msgbox('Please select an .pcd file');
                return; %退出程序
                
            end
            
            
            %% 刷新变量
            
        end

        % Menu selected function: SaveData
        function SaveDataMenuSelected(app, event)
            
            %% 保存数据


        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            
            %%  清除数据
            
            app.pc_number = 0; %  重新赋值
            app.pcs = {};
            app.pc_name = {};
            app.Featurepcs_Names = {};
            
            app.FeatureExtractButton.Enable = 'off';
            app.registrationMenuButton.Enable = 'off';
            
            cla(app.UIAxes);
            
            app.UpLoadResultTextArea('Cleared Datas !');
            
            updata(app);
            
            
        end

        % Menu selected function: FeatureExtractButton
        function FeatureExtractButtonMenuSelected(app, event)
            
            %% 特征提取 按钮
            
%             app.UpLoadResultTextArea('Feature data start !');
            
            if(find(strcmp(app.Featurepcs_Names,app.FeatureExtract_cloudpoint_{1}.Name),1))
               
                selection = uiconfirm(app.UIFigure,[app.FeatureExtract_cloudpoint_{1}.Name,' Has been extracted Feature! selected 确定 will execute it one more time, and feature informartion will be cleared.'],...
                    'Confirmation');
                switch selection
                    
                    case '确定'
                        
                        app.Boundary_delete_Feature;
                        app.UpLoadResultTextArea('Feature data start !');
                        app.FeatureAPP = FeatureExtract_APP(app);  % 跳转至特征提取的界面
                        
                    case '取消'
                        return
                        
                end
            else
                
                app.UpLoadResultTextArea('Feature data start !');
                app.FeatureAPP = FeatureExtract_APP(app);  % 跳转至特征提取的界面
                
            end
            
            
%             app.FeatureAPP = FeatureExtract_APP(app);  % 跳转至特征提取的界面
%             app.UpLoadResultTextArea('Feature data start !');
            
            
        end

        % Button pushed function: seceltpc
        function seceltpcButtonPushed(app, event)
            
            %% 选择点云
            
            app.seceltpcButton_idx  = ismember(app.pc_name, app.ListBox.Value);  % 返回索引串
            
            app.FeatureExtract_cloudpoint_ = app.pcs(app.seceltpcButton_idx);
            
            app.cloudpont_seceltpcButtonPushed = app.FeatureExtract_cloudpoint_{1}.pCloud;
            
            app.UpLoadResultTextArea(['Has been selected ' , app.FeatureExtract_cloudpoint_{1}.Name , ' !']);
            
            if(~isempty(app.cloudpont_seceltpcButtonPushed))
                
                app.FeatureExtractButton.Enable = 'on' ;
                
            end
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            
            selection = uiconfirm(app.UIFigure,'Close the figure window?',...
                'Confirmation');
            
            switch selection
                case '确定'
                    delete(app.UIFigure);
                    delete(app)
                    
                    
                case '取消'
                    return
                    
            end
            
            
        end

        % Callback function
        function SingalShowButtonPushed(app, event)
            
            
            for i = 1 : length(app.pcs)
                
                if ( isfield ( app.pcs{i}.Feature,'TurningPoint') )
                    
                    figure('Name',app.pcs{i}.Name);
                    
                    pcshow( app.pcs{i}.pCloud.Location , [0,0,0] , 'MarkerSize', 3 );
                    
                    hold on
                    
                    for j = 1 : length(app.pcs{1}.Feature.SubBoundaryInformation.Points)
                        
                        pcshow( app.pcs{i}.Feature.SubBoundaryInformation.Points(j).Line,app.Boundary_Colormatrix(j,1:3),'MarkerSize',40);
                        
                    end
                    
                    hold off
                    
                end
                
            end
            
            
        end

        % Menu selected function: registrationMenuButton
        function registrationMenuButtonSelected(app, event)
            
            %% 点云拼接按钮
            
            app.PcloudRegisterAPP = PcloudRegister_APP(app);
            
%             app.ResulttextArea
            
            
        end

        % Callback function
        function LoadFeatureButtonPushed(app, event)


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '文件';
            app.LoadData = uimenu(app.Menu);
            app.LoadData.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadData.Text = '加载数据';
            app.SaveData = uimenu(app.Menu);
            app.SaveData.MenuSelectedFcn = createCallbackFcn(app, @SaveDataMenuSelected, true);
            app.SaveData.Text = '保存数据';
            app.FeatureExtractButton = uimenu(app.UIFigure);
            app.FeatureExtractButton.MenuSelectedFcn = createCallbackFcn(app, @FeatureExtractButtonMenuSelected, true);
            app.FeatureExtractButton.Text = '特征提取';
            app.Menu_7 = uimenu(app.UIFigure);
            app.Menu_7.Text = '点云配准';
            app.registrationMenuButton = uimenu(app.Menu_7);
            app.registrationMenuButton.MenuSelectedFcn = createCallbackFcn(app, @registrationMenuButtonSelected, true);
            app.registrationMenuButton.Text = '点云拼接';
            app.Menu_8 = uimenu(app.UIFigure);
            app.Menu_8.Text = '成果导出';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '当前导入点云个数：';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Editable = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '点云数据名称';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.ClearDataButton = uibutton(app.UIFigure, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = '数据清除';
            app.seceltpc = uibutton(app.UIFigure, 'push');
            app.seceltpc.ButtonPushedFcn = createCallbackFcn(app, @seceltpcButtonPushed, true);
            app.seceltpc.Text = '选择点云';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '点云特征列表';
            app.FeatureNameListBox = uilistbox(app.UIFigure);
            app.FeatureNameListBox.Items = {};
            app.FeatureNameListBox.Value = {};
            app.ResulttextArea = uitextarea(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '操作信息栏：';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Boundary

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end