classdef FeatureExtract_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        BondaryExtractPanel             matlab.ui.container.Panel
        BoundaryExtractButton           matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        BE_SearchNeigPsnum_EditField    matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        BE_Forcethreshold_EditField     matlab.ui.control.NumericEditField
        ShowInFigureButton_Boundary     matlab.ui.control.Button
        TuringPointsExtractPanel        matlab.ui.container.Panel
        TurningExtractButton            matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        TPE_neighborPs_num_EditField    matlab.ui.control.NumericEditField
        EditField_4Label                matlab.ui.control.Label
        TPE_TurPthreshold_EditField     matlab.ui.control.NumericEditField
        ShowInFigureButton_TuringPoints  matlab.ui.control.Button
        Label_6                         matlab.ui.control.Label
        TPE_Partradius_EditField        matlab.ui.control.NumericEditField
        SaveFeatureButton               matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        BoundaryExtractedLamp           matlab.ui.control.Lamp
        Label_4                         matlab.ui.control.Label
        TuringExtLamp                   matlab.ui.control.Lamp
        SubbondaryExtractPanel          matlab.ui.container.Panel
        SubboundaryExtractButton        matlab.ui.control.Button
        ShowInFigureButton_SubBoundary  matlab.ui.control.Button
        Label_7                         matlab.ui.control.Label
        SE_CNoramlneigPsnum_EditField   matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        SubBoundaryExtLamp              matlab.ui.control.Lamp
    end

    
    properties (Access = public )  % 内部参数
        
        FeatureExtract_cp;  % 需要处理的点云数据
        
        TurningPointInformation;  % 提取的拐点 包括两个部分： [ 在全局点云中的索引 | 点集 ]
        
        Boundaryinformation; % 边界信息 包括两部分： [ 边界点在点云中的索引 | 边界点三维坐标 ]
        
        pc_FeatureInformation; % 点云数据提取出来的 点云
        
        SubBoundaryInformation; %子边界信息 包含四部分 [ 拐点在全局点云中的索引 | 线集在全局点云中的索引 | 拐点坐标 | subBound集坐标 ]
        %                    [ turning_global_indx | Subboundary_global_indx | TurningPointInformations | SubboundaryPoints ]
        
        pt3; % 处理过程中 整体点云的三维坐标
        
        %         Misson_Flag; % 判断数据是否处理结束
        Cellapp;
        
        DefaultParameters;
        
        
    end


    methods ( Access = private)   %% 内部函数
        
        function FEpcshow_(app,pointcloud)    % 点云显示
            
            plot3(app.UIAxes,pointcloud.Location(:,1),pointcloud.Location(:,2),pointcloud.Location(:,3),'.');
            
        end
        
        function boundary_pcshow(app,pointcloud,Boundary)   % 边界点云显示
            
            FEpcshow_(app,pointcloud)
            hold(app.UIAxes,'on');
            plot3(app.UIAxes,Boundary(:,1),Boundary(:,2),Boundary(:,3),'.');
            
        end
        
        function turningpoint_pcshow(app,pointcloud,Boundary,Turningpoints)   % 边界点云显示
            
            boundary_pcshow(app,pointcloud,Boundary);
            plot3(app.UIAxes,Turningpoints(:,1),Turningpoints(:,2),Turningpoints(:,3),'o');
            
        end
        
        function closeFig(app)   % 关闭请求
            
            selection = uiconfirm(app.UIFigure,'Close the figure window?',...
                'Confirmation');
            
            switch selection
                case '确定'
                    app.Cellapp.UIFigure.Visible = 'on';
                    delete(app.UIFigure);
                    delete(app)
                    
                case '取消'
                    return
            end
            
        end
        
        
        function SetParameters(app)
            
            app.DefaultParameters.neighborPs_num = 50;
            app.DefaultParameters.TurPthreshold = 2.8;
            app.DefaultParameters.Partradius = 0.003;
            app.DefaultParameters.BE_SearchNeigPsnum = 20 ;
            app.DefaultParameters.BE_Forcethreshold = 0.55 ;
            app.DefaultParameters.SE_CNoramlneigPsnum = 15;
            
            app.TPE_neighborPs_num_EditField.Value = app.DefaultParameters.neighborPs_num;
            app.TPE_Partradius_EditField.Value = app.DefaultParameters.Partradius;
            app.TPE_TurPthreshold_EditField.Value = app.DefaultParameters.TurPthreshold;
            
            app.BE_SearchNeigPsnum_EditField.Value = app.DefaultParameters.BE_SearchNeigPsnum;
            app.BE_Forcethreshold_EditField.Value = app.DefaultParameters.BE_Forcethreshold;
            
            app.SE_CNoramlneigPsnum_EditField.Value = app.DefaultParameters.SE_CNoramlneigPsnum;
            
        end
        
        
        function Notionmess(app,message)  % 显示 message 信息，无响应
            
            selection = uiconfirm(app.UIFigure,message,...
                'Confirmation');
            switch selection
                
                case '确定'
                    return;
                    
                case '取消'
                    return
                    
            end
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            
            %             app.UIFigure.WindowStyle = 'modal';  % before version2020 无法设置 UIFigure 为 modal
            
            app.Cellapp = Mainapp;
            app.FeatureExtract_cp = Mainapp.cloudpont_seceltpcButtonPushed; %对需要处理的点赋值
            
            app.TurningPointInformation;  % 用于存储拐点
            app.Boundaryinformation; % 用于储存边界信息
            app.pt3;
            
            app.TurningExtractButton.Enable = 'off';  % 初始不可点击拐点提取按钮
            app.SubboundaryExtractButton.Enable = 'off';
            app.SaveFeatureButton.Enable = 'off';
            
            app.ShowInFigureButton_Boundary.Enable = 'off';
            app.ShowInFigureButton_SubBoundary.Enable = 'off';
            app.ShowInFigureButton_TuringPoints.Enable = 'off';
            
            FEpcshow_(app,app.FeatureExtract_cp);
            
            app.DefaultParameters = [];
            app.SetParameters;
            
            app.Cellapp.UIFigure.Visible = 'off';
            
            
            %% 信号灯
            app.BoundaryExtractedLamp.Color = 'r';
            app.TuringExtLamp.Color = 'r';
            app.SubBoundaryExtLamp.Color = 'r';


        end

        % Button pushed function: BoundaryExtractButton
        function BoundaryExtractButtonPushed(app, event)
            
            %% 边界提取按钮
            
            SearchNeigPsnum = app.BE_SearchNeigPsnum_EditField.Value;
            Forcethreshold = app.BE_Forcethreshold_EditField.Value;
            app.pt3 =  double(app.FeatureExtract_cp.Location);
            
            
            if( SearchNeigPsnum < 4 )
                
                selection = uiconfirm(app.UIFigure,'The "邻近点个数" value must over 4 !',...
                    'Confirmation');
                switch selection
                    
                    case '确定'
                        return;
                        
                    case '取消'
                        return
                        
                end
                
            end


            if(~isempty(app.Boundaryinformation))
                
                selection = uiconfirm(app.UIFigure,'Boundary featuer has been executed ! once again ? the old data will be cleared ! ',...
                    'Confirmation');
                switch selection
                    
                    case '确定'
                        app.BoundaryExtractButton.Enable = 'off';
                        
                        Boundary = BoundaryExtraction( app.pt3 , SearchNeigPsnum , Forcethreshold);
                        Boundarypoints = app.pt3(Boundary(:,2),1:3);
                        
                        app.Boundaryinformation.BoundaryPoints = Boundarypoints;
                        app.Boundaryinformation.globalidx = Boundary;
                        
                    case '取消'
                        return
                        
                end
                
            else
                
                app.BoundaryExtractButton.Enable = 'off';
                
                h = waitbar(0,'Extracting Boundary!');              
%                 app.pt3 =  app.FeatureExtract_cp.Location;
                
                Boundary = BoundaryExtraction( app.pt3 , SearchNeigPsnum , Forcethreshold);
                
                Boundarypoints = app.pt3(Boundary(:,2),1:3);  % n*3
                
                app.Boundaryinformation.BoundaryPoints = Boundarypoints;
                app.Boundaryinformation.globalidx = Boundary;
                
                close(h);
                
            end
            
            
            boundary_pcshow(app,app.FeatureExtract_cp,Boundarypoints);
            
            app.BoundaryExtractButton.Enable = 'on';
            app.TurningExtractButton.Enable = 'on';
            app.BoundaryExtractedLamp.Color = 'g';  % 信号灯
            app.ShowInFigureButton_Boundary.Enable = 'on';
            
            
        end

        % Button pushed function: TurningExtractButton
        function TurningExtractButtonPushed(app, event)
            
            
            %% 拐点提取算法
            
            neighborPs_num = app.TPE_neighborPs_num_EditField.Value ;
            TurPthreshold = app.TPE_TurPthreshold_EditField.Value ;
            Partradius = app.TPE_Partradius_EditField.Value ;
            
            
            if( neighborPs_num < 4 )
                
                selection = uiconfirm(app.UIFigure,'The "邻近点数目" value must over 4 !',...
                    'Confirmation');
                switch selection
                    
                    case '确定'
                        return;
                        
                    case '取消'
                        return
                        
                end
                
            end
            
            
            if( ~isempty(app.TurningPointInformation) )
                
                selection = uiconfirm(app.UIFigure,'Turning Points featuer has been executed ! once again ? the old data will be cleared !',...
                    'Confirmation');
                switch selection
                    
                    case '确定'
                        app.TurningExtractButton.Enable = 'off';
                        app.TurningPointInformation = [];
                        
                        TurningPoint_global_indx = Detection_TurningPoint( app.pt3 , app.Boundaryinformation.globalidx , neighborPs_num , TurPthreshold , Partradius);
                        
                        app.TurningPointInformation.Points = app.pt3(TurningPoint_global_indx,1:3); % n*3
                        app.TurningPointInformation.Globalindx = TurningPoint_global_indx;
                        
                    case '取消'
                        return
                        
                end
                
            else
                
                app.TurningExtractButton.Enable = 'off';       
                TurningPoint_global_indx = Detection_TurningPoint( app.pt3 , app.Boundaryinformation.globalidx , neighborPs_num , TurPthreshold , Partradius);                
                app.TurningPointInformation.Points = app.pt3(TurningPoint_global_indx,1:3); % n*3
                app.TurningPointInformation.Globalindx = TurningPoint_global_indx;                
                
            end
            
            
%             app.TurningExtractButton.Enable = 'off';
%             
%             TurningPoint_global_indx = Detection_TurningPoint( app.pt3 , app.Boundaryinformation.globalidx , neighborPs_num , TurPthreshold , Partradius);
%             
%             app.TurningPointInformation.Points = app.pt3(TurningPoint_global_indx,1:3); % n*3
%             app.TurningPointInformation.Globalindx = TurningPoint_global_indx;
            
            turningpoint_pcshow(app,app.FeatureExtract_cp,app.Boundaryinformation.BoundaryPoints,app.TurningPointInformation.Points);
            
            app.TurningExtractButton.Enable = 'on';
            app.SubboundaryExtractButton.Enable = 'on';
            app.TuringExtLamp.Color = 'g'; % 信号灯
            app.ShowInFigureButton_TuringPoints.Enable = 'on';
            
            
        end

        % Button pushed function: SaveFeatureButton
        function SaveFeatureButtonPushed(app, event)
            
            
            CNoramlneigPsnum = app.SE_CNoramlneigPsnum_EditField.Value;
            
            if ( isempty(app.SubBoundaryInformation) )
                
                selection = uiconfirm(app.UIFigure,'SubBoundary Extract Mission is not over ！',...
                    'Confirmation');
                
                switch selection
                    
                    case '确定'
                        return;
                        
                    case '取消'
                        return
                        
                end
                
            end
            
            
            if ( isempty(app.TurningPointInformation) )
                
                selection = uiconfirm(app.UIFigure,'TuringPoint Extract Mission is not over ！',...
                    'Confirmation');
                switch selection
                    
                    case '确定'
                        return;
                        
                    case '取消'
                        return
                        
                end
                
            end
            
            
            if ( isempty(app.Boundaryinformation) )
                
                selection = uiconfirm(app.UIFigure,'Boundary Extract Mission is not over ！',...
                    'Confirmation');
                
                switch selection
                    
                    case '确定'
                        return;
                        
                    case '取消'
                        return
                        
                end
                
            end
            
            Lines = NormalExtraction(app, app.pt3 , app.SubBoundaryInformation.Globalindx.global_indx,CNoramlneigPsnum);
            %             app.SubBoundaryInformation.


            app.pc_FeatureInformation.SubBoundaryInformation = app.SubBoundaryInformation;
            app.pc_FeatureInformation.TurningPoint = app.TurningPointInformation;
            app.pc_FeatureInformation.Boundaryinformation = app.Boundaryinformation;
            app.pc_FeatureInformation.pc = app.FeatureExtract_cp;
            
            Boundary_updata_Feature(app.Cellapp,app.pc_FeatureInformation);
            
            if(isempty(app.Cellapp.Featurepcs_Names))
                
                app.Cellapp.Featurepcs_Names = {app.Cellapp.FeatureExtract_cloudpoint_{1}.Name};
                
            else
                
                app.Cellapp.Featurepcs_Names{length(app.Cellapp.Featurepcs_Names)+1} = app.Cellapp.FeatureExtract_cloudpoint_{1}.Name;
                
            end
            
            
            app.Cellapp.FeatureNameListBox.Items = app.Cellapp.Featurepcs_Names;
            
           
            if( length( app.Cellapp.Featurepcs_Names ) > 1)
                
                app.Cellapp.registrationMenuButton.Enable = 'on';
                
            end
            
            
            app.closeFig;
            
            
        end

        % Button pushed function: SubboundaryExtractButton
        function SubboundaryExtractButtonPushed(app, event)
            
            app.SubboundaryExtractButton.Enable = 'off';
            
            %% 子边界提取
            
            [Lines,LinesPoints] = LineExtraction(app.pt3,app.Boundaryinformation.globalidx,app.TurningPointInformation.Globalindx);
            app.SubBoundaryInformation.Globalindx.turning_global_indx = Lines.TurningPoint;
            app.SubBoundaryInformation.Globalindx.Subboundary_global_indx = Lines.Line;
            app.SubBoundaryInformation.Globalindx.global_indx = Lines;
            app.SubBoundaryInformation.Points = LinesPoints;
            
            app.SubBoundaryExtLamp.Color = 'g';
            app.SaveFeatureButton.Enable = 'on';
            app.ShowInFigureButton_SubBoundary.Enable = 'on';
            
            
        end

        % Button pushed function: ShowInFigureButton_SubBoundary
        function ShowInFigureButton_SubBoundaryPushed(app, event)
            
            %% 子边界信息展示
            
            Colormatrix = [1,0,0;
                0,1,0;
                0,0,1;
                1,0,1;
                1,0,1;
                0,1,1];
            
            
            figure('Name','子边界特征提取结果','NumberTitle','off');
            
            pcshow(app.pt3,[0,0,0],'MarkerSize',3);
            
            hold on;
            
            for i = 1 : length(app.SubBoundaryInformation.Points)
                
                pcshow(app.SubBoundaryInformation.Points(i).Line,Colormatrix(i,1:3),'MarkerSize',50);
                
            end
            
            
        end

        % Button pushed function: ShowInFigureButton_Boundary
        function ShowInFigureButton_BoundaryPushed(app, event)
            
            %% 边界信息展示
            pcshow(app.Boundaryinformation.BoundaryPoints,[0,1,0],'MarkerSize',50);
            
        end

        % Button pushed function: ShowInFigureButton_TuringPoints
        function ShowInFigureButton_TuringPointsPushed(app, event)
            
            %% 拐点信息展示
            pcshow(app.TurningPointInformation.Points,[1,0,0],'MarkerSize',100);
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            
            selection = uiconfirm(app.UIFigure,'Mission is not over ！ Close the figure window?',...
                'Confirmation');
            
            switch selection
                
                case '确定'
                    app.Cellapp.UIFigure.Visible = 'on';
                    delete(app.UIFigure);
                    delete(app)
                    
                case '取消'
                    return
                    
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BondaryExtractPanel = uipanel(app.UIFigure);
            app.BondaryExtractPanel.Title = '边界提取功能框';
            app.BoundaryExtractButton = uibutton(app.BondaryExtractPanel, 'push');
            app.BoundaryExtractButton.ButtonPushedFcn = createCallbackFcn(app, @BoundaryExtractButtonPushed, true);
            app.BoundaryExtractButton.Text = '边界提取';
            app.Label = uilabel(app.BondaryExtractPanel);
            app.Label.Text = '邻近点数目';
            app.BE_SearchNeigPsnum_EditField = uieditfield(app.BondaryExtractPanel, 'numeric');
            app.EditField_3Label = uilabel(app.BondaryExtractPanel);
            app.EditField_3Label.Text = '合力阈值';
            app.BE_Forcethreshold_EditField = uieditfield(app.BondaryExtractPanel, 'numeric');
            app.ShowInFigureButton_Boundary = uibutton(app.BondaryExtractPanel, 'push');
            app.ShowInFigureButton_Boundary.ButtonPushedFcn = createCallbackFcn(app, @ShowInFigureButton_BoundaryPushed, true);
            app.ShowInFigureButton_Boundary.Text = 'Show In Figure';
            app.TuringPointsExtractPanel = uipanel(app.UIFigure);
            app.TuringPointsExtractPanel.Title = '拐点提取功能框';
            app.TurningExtractButton = uibutton(app.TuringPointsExtractPanel, 'push');
            app.TurningExtractButton.ButtonPushedFcn = createCallbackFcn(app, @TurningExtractButtonPushed, true);
            app.TurningExtractButton.Text = '拐点提取';
            app.Label_2 = uilabel(app.TuringPointsExtractPanel);
            app.Label_2.Text = '邻近点个数';
            app.TPE_neighborPs_num_EditField = uieditfield(app.TuringPointsExtractPanel, 'numeric');
            app.EditField_4Label = uilabel(app.TuringPointsExtractPanel);
            app.EditField_4Label.Text = '分量和阈值';
            app.TPE_TurPthreshold_EditField = uieditfield(app.TuringPointsExtractPanel, 'numeric');
            app.ShowInFigureButton_TuringPoints = uibutton(app.TuringPointsExtractPanel, 'push');
            app.ShowInFigureButton_TuringPoints.ButtonPushedFcn = createCallbackFcn(app, @ShowInFigureButton_TuringPointsPushed, true);
            app.ShowInFigureButton_TuringPoints.Text = 'Show In Figure';
            app.Label_6 = uilabel(app.TuringPointsExtractPanel);
            app.Label_6.Text = '局部响应半径';
            app.TPE_Partradius_EditField = uieditfield(app.TuringPointsExtractPanel, 'numeric');
            app.SaveFeatureButton = uibutton(app.UIFigure, 'push');
            app.SaveFeatureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFeatureButtonPushed, true);
            app.SaveFeatureButton.Text = '保存数据并退出';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Enable = 'off';
            app.Label_3.Text = '执行结束信号';
            app.BoundaryExtractedLamp = uilamp(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = '执行结束信号';
            app.TuringExtLamp = uilamp(app.UIFigure);
            app.SubbondaryExtractPanel = uipanel(app.UIFigure);
            app.SubbondaryExtractPanel.Title = '子边界提取';
            app.SubboundaryExtractButton = uibutton(app.SubbondaryExtractPanel, 'push');
            app.SubboundaryExtractButton.ButtonPushedFcn = createCallbackFcn(app, @SubboundaryExtractButtonPushed, true);
            app.SubboundaryExtractButton.Text = '子边界提取';
            app.ShowInFigureButton_SubBoundary = uibutton(app.SubbondaryExtractPanel, 'push');
            app.ShowInFigureButton_SubBoundary.ButtonPushedFcn = createCallbackFcn(app, @ShowInFigureButton_SubBoundaryPushed, true);
            app.ShowInFigureButton_SubBoundary.Text = 'Show In Figure';
            app.Label_7 = uilabel(app.SubbondaryExtractPanel);
            app.Label_7.Text = '法线计算邻近点个数：';
            app.SE_CNoramlneigPsnum_EditField = uieditfield(app.SubbondaryExtractPanel, 'numeric');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Enable = 'off';
            app.Label_5.Text = '执行结束信号';
            app.SubBoundaryExtLamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = FeatureExtract_APP(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end