classdef PcloudRegister_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        SelectIntoButton              matlab.ui.control.Button
        SelectOutButton               matlab.ui.control.Button
        SelectSureButton              matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        alternativeListBox            matlab.ui.control.ListBox
        Label_2                       matlab.ui.control.Label
        selectedListBox               matlab.ui.control.ListBox
        ShowselectedReultButton       matlab.ui.control.Button
        Panel_2                       matlab.ui.container.Panel
        SelectColorListBox            matlab.ui.control.ListBox
        SelectColorListBox2           matlab.ui.control.ListBox
        Color1Label                   matlab.ui.control.Label
        Color2Label                   matlab.ui.control.Label
        CheckcorrespondButton         matlab.ui.control.Button
        correspondSureButton          matlab.ui.control.Button
        SelectColorButton             matlab.ui.control.Button
        SelectColorListBoxNameLabel   matlab.ui.control.EditField
        SelectColorListBox2NameLabel  matlab.ui.control.EditField
        Panel_3                       matlab.ui.container.Panel
        ICPCheckBox                   matlab.ui.control.CheckBox
        NICPCheckBox                  matlab.ui.control.CheckBox
        RegisterButton                matlab.ui.control.Button
        RegistrationResultShowButton  matlab.ui.control.Button
        Label_3                       matlab.ui.control.Label
        NormalweightEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        
        SelectPcloud;
        SelectPcloud_Name;
        Feature_PCloud_Name; 
        PcloudDatas;
        PcloudDatas_num;
        Feature_Pcloud_Data1;
        Feature_Pcloud_Data2;
        
        CallAPP;
        
        correspond_Lineindx1;
        correspond_Lineindx2;
        
        Boundary_Colormatrix;
        Boundary_Colormatrix_Name;
        
        Trans_Feature_Pcloud_Data2;
        
        
    end
    
    methods (Access = private)
        
        function FindedpCloud = findpCloud(app,Name)   % 从PcloudDatas 中找出名为 Name 的项目
            
            for i = 1 : app.PcloudDatas_num
                
                if(app.PcloudDatas{i}.Name == Name)
                    
                    FindedpCloud = app.PcloudDatas{i};
                    
                end
                
            end
            
        end
        
        
        function pcshow_(app,pCloud)  % 可视化点云数据的特征信息
            
            figure('Name',pCloud.Name);
            
            pcshow( pCloud.pCloud.Location , [0,0,0] , 'MarkerSize', 3 );
            
            hold on
            
            for j = 1 : length(pCloud.Feature.SubBoundaryInformation.Points)
                
                pcshow( pCloud.Feature.SubBoundaryInformation.Points(j).Line,app.Boundary_Colormatrix(j,1:3),'MarkerSize',40);
                
            end
            
            hold off
            
        end
        
    
        function pcshow_correspondLine(app,pCloud,indx) % 可视化点云项目中选择的边信息
            
            
            figure('Name',pCloud.Name);
            
            pcshow( pCloud.pCloud.Location , [0,0,0] , 'MarkerSize', 3 );
            
            hold on
            
            pcshow( pCloud.Feature.SubBoundaryInformation.Points(indx).Line,app.Boundary_Colormatrix(indx,1:3),'MarkerSize',40);
            
            hold off
            
        end
        
    
        function Notionmess(app,message)  % 显示 message 信息，无响应
             
            selection = uiconfirm(app.UIFigure,message,...
                'Confirmation');
            switch selection
                
                case '确定'
                    return;
                    
                case '取消'
                    return
                    
            end
            
        end
    
    
        function RegistrationResultShow(app)  % 显示配准结果
            
            close all
            
            Line1 = app.Feature_Pcloud_Data1.Feature.SubBoundaryInformation.Points(app.correspond_Lineindx1).Line;
%             Line2 = app.Feature_Pcloud_Data2.Feature.SubBoundaryInformation.Points(app.correspond_Lineindx2).Line;
            normal1 = app.Feature_Pcloud_Data1.Feature.SubBoundaryInformation.Points(app.correspond_Lineindx1).Normal;
%             normal2 = app.Feature_Pcloud_Data2.Feature.SubBoundaryInformation.Points(app.correspond_Lineindx2).Normal;
            
            figure('Name','配准结果1')
            
            pcshow(app.Feature_Pcloud_Data1.Feature.SubBoundaryInformation.Points(app.correspond_Lineindx1).Line,[1,0,0],'MarkerSize',50);
            hold on
            pcshow(app.Trans_Feature_Pcloud_Data2.Feature.SubBoundaryInformation.Points(app.correspond_Lineindx2).Line,[0,1,0],'MarkerSize',50);
            
            if(app.NICPCheckBox.Value == true )                
                
                quiver3(Line1(:,1),Line1(:,2),Line1(:,3),normal1 (:,1),normal1 (:,2),normal1(:,3));
                
                trans_line = app.Trans_Feature_Pcloud_Data2.Feature.SubBoundaryInformation.Points(app.correspond_Lineindx2).Line;
                trans_Normal = app.Trans_Feature_Pcloud_Data2.Feature.SubBoundaryInformation.Points(app.correspond_Lineindx2).Normal;
                
                quiver3(trans_line(:,1),trans_line(:,2),trans_line(:,3),trans_Normal(:,1),trans_Normal(:,2),trans_Normal(:,3));
                
            end
            
            hold off
            
            
            figure('Name','配准结果2');
            
            pcshow(app.Feature_Pcloud_Data1.Feature.SubBoundaryInformation.Points(app.correspond_Lineindx1).Line,[1,0,0],'MarkerSize',50);
            hold on
            pcshow(app.Trans_Feature_Pcloud_Data2.Feature.SubBoundaryInformation.Points(app.correspond_Lineindx2).Line,[0,1,0],'MarkerSize',50);
            pcshow(app.Feature_Pcloud_Data1.pCloud.Location,[0,0,0]);
            pcshow(app.Trans_Feature_Pcloud_Data2.Feature.Transed,[0,0,0]);
            hold off
            
        end
    
    
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainAPP)
            
            app.SelectPcloud = [];
            app.Feature_Pcloud_Data1 = [];
            app.Feature_Pcloud_Data2 = [];
            
            app.CallAPP = MainAPP;
            app.Feature_PCloud_Name = MainAPP.Featurepcs_Names;                        
            app.alternativeListBox.Items = app.Feature_PCloud_Name;
            app.PcloudDatas = MainAPP.pcs;
            app.PcloudDatas_num = MainAPP.pc_number;
            
            app.correspond_Lineindx1;
            app.correspond_Lineindx2;
            
            app.Trans_Feature_Pcloud_Data2 = [];
            app.NormalweightEditField.Value = 0.003;
            
            
            app.Boundary_Colormatrix = [1,0,0;
                0,1,0;
                0,0,1;
                1,0,1;
                1,0,1;
                0,1,1];
            app.Boundary_Colormatrix_Name = [{'红'},{'绿'},{'蓝'},{'粉'}];
            
            
            app.RegistrationResultShowButton.Enable = 'off' ;
            app.RegisterButton.Enable = 'off' ;
            app.CallAPP.UIFigure.Visible = 'off' ; 
            app.NormalweightEditField.Editable = 'off';
            
        end

        % Button pushed function: SelectIntoButton
        function SelectIntoButtonPushed(app, event)
            
            %% 选择用于配准的点云
            
            app.SelectPcloud_Name{length(app.SelectPcloud_Name)+1} = app.alternativeListBox.Value; 
            app.selectedListBox.Items = app.SelectPcloud_Name;                       
            
            
        end

        % Button pushed function: SelectOutButton
        function SelectOutButtonPushed(app, event)
            
            %% 选出点云
            
            indx = strcmp( app.SelectPcloud_Name , app.selectedListBox.Value);
            indx = not(indx);
            app.SelectPcloud_Name = app.SelectPcloud_Name(indx);
            app.selectedListBox.Items = app.SelectPcloud_Name;
            
            
        end

        % Button pushed function: SelectSureButton
        function SelectSureButtonPushed(app, event)
            
            %% 确定选择数据
            
            if(length(app.SelectPcloud_Name) > 2 )
                
                selection = uiconfirm(app.UIFigure,'The number of selected over 2 ！ Please select 2 pointcloud',...
                'Confirmation');
                switch selection
                
                    case '确定'
                        return;
                   
                    case '取消'
                        return
                    
                end
                
                
            elseif( length(app.SelectPcloud_Name) < 2 )
                                
                selection = uiconfirm(app.UIFigure,'The number of selected lower 2 ！ Please select 2 pointcloud',...
                'Confirmation');
                switch selection
                
                    case '确定'
                        return;
                   
                    case '取消'
                        return
                    
                end
                
            else
                
                app.Feature_Pcloud_Data1 =  findpCloud(app,app.SelectPcloud_Name{1});
                app.Feature_Pcloud_Data2 =  findpCloud(app,app.SelectPcloud_Name{2});
                
                app.SelectIntoButton.Enable = 'off';
                app.SelectOutButton.Enable = 'off';
                app.SelectSureButton.Enable = 'off';
                
                pcshow_(app,app.Feature_Pcloud_Data1);
                pcshow_(app,app.Feature_Pcloud_Data2);     
                
                app.SelectColorListBoxNameLabel.Value = app.Feature_Pcloud_Data1.Name;
                app.SelectColorListBox2NameLabel.Value = app.Feature_Pcloud_Data2.Name;
                
                app.SelectColorListBox.Items = app.Boundary_Colormatrix_Name;
                app.SelectColorListBox2.Items = app.Boundary_Colormatrix_Name;
                
                
            end
                                  
            
        end

        % Button pushed function: correspondSureButton
        function correspondSureButtonPushed(app, event)
            
            %% 对应关系确定按钮
            
            
           app.SelectColorButton.Enable = 'off';
           app.correspondSureButton.Enable = 'off';
           
           app.RegisterButton.Enable = 'on';
                                   
            
        end

        % Button pushed function: SelectColorButton
        function SelectColorButtonPushed(app, event)
            
            %% 选择 对应边颜色
            
            color1 = app.SelectColorListBox.Value;  
            color2 = app.SelectColorListBox2.Value;
            
            app.Color1Label.Text = color1;
            app.Color2Label.Text = color2;
            
            color1_indx =  strcmp(app.Boundary_Colormatrix_Name,color1);
            color2_indx =  strcmp(app.Boundary_Colormatrix_Name,color2);
            
            color1_indx = find(color1_indx,1);
            color2_indx = find(color2_indx,1);
            
            app.correspond_Lineindx1 = color1_indx;
            app.correspond_Lineindx2 = color2_indx;
            
        end

        % Button pushed function: CheckcorrespondButton
        function CheckcorrespondButtonPushed(app, event)
            
            %% 展示对应边
            
            app.pcshow_correspondLine(app.Feature_Pcloud_Data1,app.correspond_Lineindx1);
            app.pcshow_correspondLine(app.Feature_Pcloud_Data2,app.correspond_Lineindx2);
            
            
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
            
            %% 配准按钮
            
            Normalweight = app.NormalweightEditField.Value;
            
            %% 检测 NICP/ICP 按钮选择
            
            if(app.ICPCheckBox.Value == false && app.NICPCheckBox.Value == false)
                
                Notionmess(app,'Plelse select one choice ！');
                app.ICPCheckBox.Value = true;
                app.NICPCheckBox.Value = false;
                
            end
            
            if(app.ICPCheckBox.Value == true && app.NICPCheckBox.Value == true)
                
                Notionmess(app,'Plelse select one choice ！');
                app.ICPCheckBox.Value = true;
                app.NICPCheckBox.Value = false;
                
            end
            
            
            %% 执行配准计算
            
            Line1 = app.Feature_Pcloud_Data1.Feature.SubBoundaryInformation.Points(app.correspond_Lineindx1).Line;
            Line2 = app.Feature_Pcloud_Data2.Feature.SubBoundaryInformation.Points(app.correspond_Lineindx2).Line;
            normal1 = app.Feature_Pcloud_Data1.Feature.SubBoundaryInformation.Points(app.correspond_Lineindx1).Normal;
            normal2 = app.Feature_Pcloud_Data2.Feature.SubBoundaryInformation.Points(app.correspond_Lineindx2).Normal;
            
            if(app.ICPCheckBox.Value == true)
                               
                T = n_ICP(Line1,Line2);                
                app.Trans_Feature_Pcloud_Data2 = TransForm_Dataset(app.Feature_Pcloud_Data2,T);                
                
            end
            
            if(app.NICPCheckBox.Value == true)
                
                T = HCode_NICP(Line1,Line2,normal1,normal2,Normalweight);
                app.Trans_Feature_Pcloud_Data2 = TransForm_Dataset(app.Feature_Pcloud_Data2,T);
                
            end
            
            %% 结果显示
            
            app.RegistrationResultShow;
            
            app.RegistrationResultShowButton.Enable = 'on';
            
            app.Notionmess(num2str([num2str(T(1,1:4)),10,num2str(T(2,1:4)),10,num2str(T(3,1:4)),10,num2str(T(4,1:4))]));
            
            app.CallAPP.UpLoadResultTextArea(num2str([num2str(T(1,1:4)),10,num2str(T(2,1:4)),10,num2str(T(3,1:4)),10,num2str(T(4,1:4))]));
            
            
        end

        % Value changed function: ICPCheckBox
        function ICPCheckBoxValueChanged(app, event)
            
            value = app.ICPCheckBox.Value;
            
            if( value == true)
                
                app.NICPCheckBox.Value = false;    
                app.NormalweightEditField.Editable = 'off';
            end
            
            if( value == false)
                
                app.NICPCheckBox.Value = true;   
                app.NormalweightEditField.Editable = 'on';
                
            end
            
            
        end

        % Value changed function: NICPCheckBox
        function NICPCheckBoxValueChanged(app, event)
            value = app.NICPCheckBox.Value;
            
            if( value == true)
                
                app.ICPCheckBox.Value = false;
                

            end
            
            if( value == false)
                
                app.ICPCheckBox.Value = true;
                app.NormalweightEditField.Editable = 'on';
                
            end
            
        end

        % Button pushed function: RegistrationResultShowButton
        function RegistrationResultShowButtonPushed(app, event)
            
            %% 配准结果展示
            app.RegistrationResultShow;    
            
            
        end

        % Button pushed function: ShowselectedReultButton
        function ShowselectedReultButtonPushed(app, event)
            
            %% 展示选择的点云结果
            
            pcshow_(app,app.Feature_Pcloud_Data1);
            pcshow_(app,app.Feature_Pcloud_Data2);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           
            selection = uiconfirm(app.UIFigure, 'Close the figure window?',...
                'Confirmation');
            
            switch selection
                
                case '确定'
                    
                    app.CallAPP.UIFigure.Visible = 'on';
                    delete(app.UIFigure);
                    delete(app)
                    
                case '取消'
                    
                    return
                    
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '选择拼接数据';
            app.SelectIntoButton = uibutton(app.Panel, 'push');
            app.SelectIntoButton.ButtonPushedFcn = createCallbackFcn(app, @SelectIntoButtonPushed, true);
            app.SelectIntoButton.Text = '>>';
            app.SelectOutButton = uibutton(app.Panel, 'push');
            app.SelectOutButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutButtonPushed, true);
            app.SelectOutButton.Text = '<<';
            app.SelectSureButton = uibutton(app.Panel, 'push');
            app.SelectSureButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSureButtonPushed, true);
            app.SelectSureButton.Text = '确定';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '备选数据';
            app.alternativeListBox = uilistbox(app.Panel);
            app.alternativeListBox.Items = {};
            app.alternativeListBox.Value = {};
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '已选择数据';
            app.selectedListBox = uilistbox(app.Panel);
            app.selectedListBox.Items = {};
            app.selectedListBox.Value = {};
            app.ShowselectedReultButton = uibutton(app.Panel, 'push');
            app.ShowselectedReultButton.ButtonPushedFcn = createCallbackFcn(app, @ShowselectedReultButtonPushed, true);
            app.ShowselectedReultButton.Text = 'show in Figure';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '选择对应边';
            app.SelectColorListBox = uilistbox(app.Panel_2);
            app.SelectColorListBox.Items = {};
            app.SelectColorListBox.Value = {};
            app.SelectColorListBox2 = uilistbox(app.Panel_2);
            app.SelectColorListBox2.Items = {};
            app.SelectColorListBox2.Value = {};
            app.Color1Label = uilabel(app.Panel_2);
            app.Color2Label = uilabel(app.Panel_2);
            app.CheckcorrespondButton = uibutton(app.Panel_2, 'push');
            app.CheckcorrespondButton.ButtonPushedFcn = createCallbackFcn(app, @CheckcorrespondButtonPushed, true);
            app.CheckcorrespondButton.Text = 'Check in Figure';
            app.correspondSureButton = uibutton(app.Panel_2, 'push');
            app.correspondSureButton.ButtonPushedFcn = createCallbackFcn(app, @correspondSureButtonPushed, true);
            app.correspondSureButton.Text = '确定';
            app.SelectColorButton = uibutton(app.Panel_2, 'push');
            app.SelectColorButton.ButtonPushedFcn = createCallbackFcn(app, @SelectColorButtonPushed, true);
            app.SelectColorButton.Text = '确定颜色';
            app.SelectColorListBoxNameLabel = uieditfield(app.Panel_2, 'text');
            app.SelectColorListBox2NameLabel = uieditfield(app.Panel_2, 'text');
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '配准';
            app.ICPCheckBox = uicheckbox(app.Panel_3);
            app.ICPCheckBox.ValueChangedFcn = createCallbackFcn(app, @ICPCheckBoxValueChanged, true);
            app.ICPCheckBox.Text = 'ICP';
            app.NICPCheckBox = uicheckbox(app.Panel_3);
            app.NICPCheckBox.ValueChangedFcn = createCallbackFcn(app, @NICPCheckBoxValueChanged, true);
            app.NICPCheckBox.Text = 'NICP';
            app.RegisterButton = uibutton(app.Panel_3, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Text = '配准';
            app.RegistrationResultShowButton = uibutton(app.Panel_3, 'push');
            app.RegistrationResultShowButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrationResultShowButtonPushed, true);
            app.RegistrationResultShowButton.Text = 'Result show';
            app.Label_3 = uilabel(app.Panel_3);
            app.Label_3.Text = '法线权重';
            app.NormalweightEditField = uieditfield(app.Panel_3, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = PcloudRegister_APP(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end